classdef afmxyznanoholeanalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        UndowithCtrlZLabel     matlab.ui.control.Label
        SmoothZvaluesButton    matlab.ui.control.Button
        DrawRectangleButton    matlab.ui.control.Button
        EnterAFMxyzfileButton  matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        centerMarkers = matlab.graphics.chart.primitive.Line.empty;

        full_file_path
        drawnRectangles
        Xvec
        Yvec
        Zgrid

        H_profile_X
        H_profile_Z
        V_profile_Y
        V_profile_Z

        H_pointsNeeded
        V_pointsNeeded

        deleteToleranceFactor

        H_boundaryIndices;
        V_boundaryIndices;

        V_selectedPoints;
        H_selectedPoints;

        H_interp_x1
        H_interp_z1
        H_interp_x2
        H_interp_z2

        H_interp_x3
        H_interp_z3
        H_interp_x4
        H_interp_z4

        H_interp_x
        H_interp_z
        V_interp_y
        V_interp_z

        V_interp_y1
        V_interp_z1
        V_interp_y2
        V_interp_z2

        V_interp_y3
        V_interp_z3
        V_interp_y4
        V_interp_z4

        H_interpLines
        V_interpLines

        H_

        minZval;
        Diameters_COM_X = []   
        Diameters_COM_Y = []    
        Diameters_Peak_X = []  
        Diameters_Peak_Y = []   
        Depth;
        rectpositions;
        PointSelectionHistory = {};     
        BoundarySelectionHistory = {};  
        
        DiametersInnerY
        DiametersInnerX
        smooth_z_values = false;

        DiametersOuterY = [];
        DiametersOuterX = [];
    end


    methods (Access = private)

        function keyPressHandler(app, event)
            % CTRL + Z → Undo last rectangle
            if ismember('control', event.Modifier) && strcmp(event.Key, 'z')
                undoLastRectangle(app);
            end
        end


        function undoLastRectangle(app)

            if isempty(app.drawnRectangles)
                return; % nothing to undo
            end

            % Delete last rectangle graphics
            h = app.drawnRectangles(end);
            if isvalid(h)
                delete(h);
            end

            % Delete center marker
            if ~isempty(app.centerMarkers)
                h = app.centerMarkers(end);
                if isvalid(h)
                    delete(h);
                end
                app.centerMarkers(end) = [];
            end

            % Remove from stacks
            app.drawnRectangles(end) = [];
            app.rectpositions(end)   = [];

            % Roll back measurement arrays safely
            if ~isempty(app.Diameters_COM_X)
                app.Diameters_COM_X(end) = [];
                app.Diameters_COM_Y(end) = [];
                app.Diameters_Peak_X(end) = [];
                app.Diameters_Peak_Y(end) = [];
                app.DiametersInnerX(end) = [];
                app.DiametersInnerY(end) = [];
                app.Depth(end) = [];
                app.DiametersOuterX(end) = [];
                app.DiametersOuterY(end) = [];
            end

            % Disable Done button if no rectangles left
            if isempty(app.drawnRectangles)
                app.DoneButton.Enable = "off";
            end
        end

        function show_COM_diagnostic(app, horiz_X, horiz_Z, vert_Y, vert_Z, XL, XR, YL, YR)
            fig = figure( ...
                'Name', 'Nanohole Diagnostic (COM, Inner & Outer Diameters)', ...
                'NumberTitle', 'off', ...
                'Color', 'w', ...
                'Position', [100 100 1100 800]);

            % --- Horizontal Inner & COM ---
            subplot(2,2,1)
            hold on
            plot(horiz_X, horiz_Z, 'k-', 'LineWidth', 1.5)
            plot(app.H_interp_x1, app.H_interp_z1, 'r', 'LineWidth', 2)
            plot(app.H_interp_x2, app.H_interp_z2, 'r', 'LineWidth', 2)
            if ~isempty(app.H_selectedPoints)
                plot(app.H_selectedPoints(:,1), app.H_selectedPoints(:,2), ...
                    'o', 'MarkerSize', 6, 'MarkerFaceColor', [0 0.6 1], 'MarkerEdgeColor', 'k');
            end
            xline(XL, '--b', 'LineWidth', 1.5, 'DisplayName', 'COM Left');
            xline(XR, '--b', 'LineWidth', 1.5, 'DisplayName', 'COM Right');
            if ~isempty(app.H_boundaryIndices)
                xline(app.H_interp_x1(app.H_boundaryIndices(1)), ':g', 'LineWidth', 2, 'DisplayName', 'Inner Left');
                xline(app.H_interp_x2(app.H_boundaryIndices(2)), ':g', 'LineWidth', 2, 'DisplayName', 'Inner Right');
            end
            xlabel('X (m)'); ylabel('Z (m)');
            title('Horizontal Profile: COM & Inner Diameter');
            grid on; box on; legend('Location', 'best'); hold off

            % --- Horizontal Outer Diameter ---
            subplot(2,2,2)
            hold on
            plot(horiz_X, horiz_Z, 'k-', 'LineWidth', 1.5)
            plot(app.H_interp_x1, app.H_interp_z1, 'r', 'LineWidth', 2)
            plot(app.H_interp_x2, app.H_interp_z2, 'r', 'LineWidth', 2)
            xline(app.H_interp_x1(1), '-.m', 'LineWidth', 2, 'DisplayName', 'Outer Left');
            xline(app.H_interp_x2(end), '-.m', 'LineWidth', 2, 'DisplayName', 'Outer Right');
            xlabel('X (m)'); ylabel('Z (m)');
            title('Horizontal Profile: Outer Diameter');
            grid on; box on; legend('Location', 'best'); hold off

            % --- Vertical Inner & COM ---
            subplot(2,2,3)
            hold on
            plot(vert_Y, vert_Z, 'k-', 'LineWidth', 1.5)
            plot(app.V_interp_y1, app.V_interp_z1, 'r', 'LineWidth', 2)
            plot(app.V_interp_y2, app.V_interp_z2, 'r', 'LineWidth', 2)
            if ~isempty(app.V_selectedPoints)
                plot(app.V_selectedPoints(:,1), app.V_selectedPoints(:,2), ...
                    'o', 'MarkerSize', 6, 'MarkerFaceColor', [0 0.6 1], 'MarkerEdgeColor', 'k');
            end
            xline(YL, '--b', 'LineWidth', 1.5, 'DisplayName', 'COM Lower');
            xline(YR, '--b', 'LineWidth', 1.5, 'DisplayName', 'COM Upper');
            if ~isempty(app.V_boundaryIndices)
                xline(app.V_interp_y1(app.V_boundaryIndices(1)), ':g', 'LineWidth', 2, 'DisplayName', 'Inner Lower');
                xline(app.V_interp_y2(app.V_boundaryIndices(2)), ':g', 'LineWidth', 2, 'DisplayName', 'Inner Upper');
            end
            xlabel('Y (m)'); ylabel('Z (m)');
            title('Vertical Profile: COM & Inner Diameter');
            grid on; box on; legend('Location', 'best'); hold off

            % --- Vertical Outer Diameter ---
            subplot(2,2,4)
            hold on
            plot(vert_Y, vert_Z, 'k-', 'LineWidth', 1.5)
            plot(app.V_interp_y1, app.V_interp_z1, 'r', 'LineWidth', 2)
            plot(app.V_interp_y2, app.V_interp_z2, 'r', 'LineWidth', 2)
            xline(app.V_interp_y1(1), '-.m', 'LineWidth', 2, 'DisplayName', 'Outer Lower');
            xline(app.V_interp_y2(end), '-.m', 'LineWidth', 2, 'DisplayName', 'Outer Upper');
            xlabel('Y (m)'); ylabel('Z (m)');
            title('Vertical Profile: Outer Diameter');
            grid on; box on; legend('Location', 'best'); hold off
        end


        function pts = auto_ring_points_strict_lateral(app, X, Z)
            % AUTO_RING_POINTS_STRICT_LATERAL
            % Outer walls: derivative sign change
            % Inner walls: Z returns to outer Z level (absolute tolerance, meters)

            % ----------------------------
            % Input sanitation
            % ----------------------------
            X = X(:);
            Z = Z(:);
            N = numel(Z);

            if N < 20
                error('Profile too short.');
            end

            % ----------------------------
            % Smooth profile for derivatives
            % ----------------------------

            if app.smooth_z_values
                Z = smoothdata(Z, "sgolay", 7);
            end

            Zs = Z;   % 7–9 is ideal for AFM
            dZ = diff(Zs);

            % ----------------------------
            % Hole center
            % ----------------------------
            [~, i_min] = min(Zs);

            % ----------------------------
            % Left & right peaks
            % ----------------------------

            [~, ipeakL] = max(Z(1:i_min));

            [~, iR] = max(Z(i_min:end));
            ipeakR = iR + i_min - 1;

            % ======================================================
            % LEFT SIDE
            % ======================================================

            iouterL = 1;
            for i = 1:(ipeakL-2)
                if dZ(i)*dZ(i+1) <= 0
                    iouterL = i+1;   % keep updating
                end
            end

            baseZL = Z(iouterL);

            Zseg = Z(ipeakL:i_min);

            mask = Zseg <= baseZL;

            if ~any(mask)
                error('Inner left wall not found: no Z <= baseZL');
            end

            idx = find(mask, 1, 'first');
            iinnerL = ipeakL + idx - 1;

            % ======================================================
            % RIGHT SIDE
            % ======================================================

            % ---- Outer right: derivative sign change (− → +)
            iouterR = N;
            for i = (ipeakR):(N-1)
                if dZ(i)*dZ(i+1) <= 0
                    iouterR = i+1;
                    break;
                end
            end

            baseZR = Z(iouterR);

            Zseg = Z(i_min:ipeakR);

            mask = Zseg <= baseZR;

            if ~any(mask)
                error('Inner right wall not found: no Z <= baseZR');
            end

            idx = find(mask, 1, 'last');   % last one before peak
            iinnerR = i_min + idx - 1;

            % ----------------------------
            % Enforce strict ordering
            % ----------------------------
            iouterL = max(1, min(iouterL, ipeakL));
            iinnerL = max(ipeakL, min(iinnerL, i_min));

            iinnerR = max(i_min, min(iinnerR, ipeakR));
            iouterR = max(ipeakR, min(iouterR, N));

            % ----------------------------
            % Output (ordered, guaranteed)
            % ----------------------------
            pts = [
                X(iouterL), Z(iouterL);
                X(ipeakL),  Z(ipeakL);
                X(iinnerL), Z(iinnerL);
                X(iouterR), Z(iouterR);
                X(ipeakR),  Z(ipeakR);
                X(iinnerR), Z(iinnerR)
                ];

        end


        function [peakLeft, peakRight] = computePeakDiameter_H(app)

            % Left ring peak
            [~, idxL] = max(app.H_interp_z1);
            peakLeft = app.H_interp_x1(idxL);

            % Right ring peak
            [~, idxR] = max(app.H_interp_z2);
            peakRight = app.H_interp_x2(idxR);
        end

        function [peakLeft, peakRight] = computePeakDiameter_V(app)

            % Left ring peak
            [~, idxL] = max(app.V_interp_z1);
            peakLeft = app.V_interp_y1(idxL);

            % Right ring peak
            [~, idxR] = max(app.V_interp_z2);
            peakRight = app.V_interp_y2(idxR);
        end


        function [comLeftX, comRightX] = compute_COM_H(app)

            if numel(app.H_boundaryIndices) ~= 2
                error('Need exactly 2 H boundary indices.');
            end

            idxA = app.H_boundaryIndices(1);
            idxB = app.H_boundaryIndices(2);

            N1 = numel(app.H_interp_x1);
            N2 = numel(app.H_interp_x2);

            if idxA <= N1
                leftStart = 1;
                leftEnd   = idxA;
            else
                error('Left boundary must be inside left ring region.');
            end

            if idxB <= N2
                rightStart = idxB;
                rightEnd   = N2;
            else
                error('Right boundary must be inside right ring region.');
            end

            xL = app.H_interp_x1(leftStart:leftEnd);
            zL = abs(app.H_interp_z1(leftStart:leftEnd)-app.H_interp_z1(leftStart));
            if sum(zL) > 0
                comLeftX = sum(zL .* xL) / sum(zL);
            else
                comLeftX = mean(xL);
            end


            xR = app.H_interp_x2(rightStart:rightEnd);
            zR = abs(app.H_interp_z2(rightStart:rightEnd)-app.H_interp_z2(rightEnd));

            if sum(zL) > 0
                comRightX = sum(zR .* xR) / sum(zR);
            else
                comRightX = mean(xL);
            end


        end


        function [comLeftY, comRightY] = compute_COM_V(app)

            if numel(app.V_boundaryIndices) ~= 2
                error('Need exactly 2 V boundary indices.');
            end

            idxA = app.V_boundaryIndices(1);
            idxB = app.V_boundaryIndices(2);

            N1 = numel(app.V_interp_y1);
            N2 = numel(app.V_interp_y2);

            if idxA <= N1
                leftStart = 1;
                leftEnd   = idxA;
            else
                error('Left V boundary must be inside left ring.');
            end

            if idxB <= N2
                rightStart = idxB;
                rightEnd   = N2;
            else
                error('Right V boundary must be inside right ring.');
            end

            yL = app.V_interp_y1(leftStart:leftEnd);
            zL = abs(app.V_interp_z1(leftStart:leftEnd) - app.V_interp_z1(leftStart));
            if sum(zL) > 0
                comLeftY = sum(zL .* yL) / sum(zL);
            else
                comLeftY = mean(yL);
            end


            yR = app.V_interp_y2(rightStart:rightEnd);
            zR = abs(app.V_interp_z2(rightStart:rightEnd) - app.V_interp_z2(rightEnd));
            if sum(zR) > 0
                comRightY = sum(zR .* yR) / sum(zR);
            else
                comRightY = mean(yR);
            end


        end

        function [Hx1, Hz1, Hx2, Hz2, Vy1, Vz1, Vy2, Vz2] = build_interpolations(app)

            %===========================
            % HORIZONTAL AXIS
            %===========================
            Hpts = app.H_selectedPoints;
            if size(Hpts,1) < 6
                return
            end

            % Sort by X (just to be safe)
            [~, idxH] = sort(Hpts(:,1));
            H_sorted = Hpts(idxH, :);

            % Define ranges for two segments (1-3 and 4-6 selected points)
            range1_start = H_sorted(1,1);
            range1_end   = H_sorted(3,1);

            range2_start = H_sorted(4,1);
            range2_end   = H_sorted(6,1);

            range3_start = H_sorted(2,1);
            range3_end   = H_sorted(3,1);

            range4_start = H_sorted(4,1);
            range4_end   = H_sorted(5,1);

            % Find indices in the original full profile within these ranges
            idx_range1 = find(app.H_profile_X >= range1_start & app.H_profile_X <= range1_end);
            idx_range2 = find(app.H_profile_X >= range2_start & app.H_profile_X <= range2_end);
            idx_range3 = find(app.H_profile_X >= range3_start & app.H_profile_X <= range3_end);
            idx_range4 = find(app.H_profile_X >= range4_start & app.H_profile_X <= range4_end);

            % Extract original data in these ranges
            Hx1_data = app.H_profile_X(idx_range1);
            Hz1_data = app.H_profile_Z(idx_range1);

            Hx2_data = app.H_profile_X(idx_range2);
            Hz2_data = app.H_profile_Z(idx_range2);

            Hx3_data = app.H_profile_X(idx_range3);
            Hz3_data = app.H_profile_Z(idx_range3);

            Hx4_data = app.H_profile_X(idx_range4);
            Hz4_data = app.H_profile_Z(idx_range4);

            % Interpolate to 1000 points in each range
            Hx1 = linspace(range1_start, range1_end, 1000);
            Hz1 = interp1(Hx1_data, Hz1_data, Hx1, 'linear');

            Hx2 = linspace(range2_start, range2_end, 1000);
            Hz2 = interp1(Hx2_data, Hz2_data, Hx2, 'linear');

            Hx3 = linspace(range3_start, range3_end, 1000);
            Hz3 = interp1(Hx3_data, Hz3_data, Hx3, 'linear');

            Hx4 = linspace(range4_start, range4_end, 1000);
            Hz4 = interp1(Hx4_data, Hz4_data, Hx4, 'linear');


            Vpts = app.V_selectedPoints;
            if size(Vpts,1) < 6
            end

            % Sort by Y
            [~, idxV] = sort(Vpts(:,1));
            V_sorted = Vpts(idxV, :);

            % Two ranges for vertical axis (1-3 and 4-6 points)
            vrange1_start = V_sorted(1,1);
            vrange1_end   = V_sorted(3,1);

            vrange2_start = V_sorted(4,1);
            vrange2_end   = V_sorted(6,1);

            vrange3_start = V_sorted(2,1);
            vrange3_end   = V_sorted(3,1);

            vrange4_start = V_sorted(4,1);
            vrange4_end   = V_sorted(5,1);

            idx_vrange1 = find(app.V_profile_Y >= vrange1_start & app.V_profile_Y <= vrange1_end);
            idx_vrange2 = find(app.V_profile_Y >= vrange2_start & app.V_profile_Y <= vrange2_end);

            idx_vrange3 = find(app.V_profile_Y >= vrange3_start & app.V_profile_Y <= vrange3_end);
            idx_vrange4 = find(app.V_profile_Y >= vrange4_start & app.V_profile_Y <= vrange4_end);

            Vy1_data = app.V_profile_Y(idx_vrange1);
            Vz1_data = app.V_profile_Z(idx_vrange1);

            Vy2_data = app.V_profile_Y(idx_vrange2);
            Vz2_data = app.V_profile_Z(idx_vrange2);

            Vy3_data = app.V_profile_Y(idx_vrange3);
            Vz3_data = app.V_profile_Z(idx_vrange3);

            Vy4_data = app.V_profile_Y(idx_vrange4);
            Vz4_data = app.V_profile_Z(idx_vrange4);

            Vy1 = linspace(vrange1_start, vrange1_end, 1000);
            Vz1 = interp1(Vy1_data, Vz1_data, Vy1, 'linear');

            Vy2 = linspace(vrange2_start, vrange2_end, 1000);
            Vz2 = interp1(Vy2_data, Vz2_data, Vy2, 'linear');

            Vy3 = linspace(vrange3_start, vrange3_end, 1000);
            Vz3 = interp1(Vy3_data, Vz3_data, Vy3, 'linear');

            Vy4 = linspace(vrange4_start, vrange4_end, 1000);
            Vz4 = interp1(Vy4_data, Vz4_data, Vy4, 'linear');

            app.H_interp_x1 = Hx1;
            app.H_interp_z1 = Hz1;
            app.H_interp_x2 = Hx2;
            app.H_interp_z2 = Hz2;

            app.V_interp_y1 = Vy1;
            app.V_interp_z1 = Vz1;
            app.V_interp_y2 = Vy2;
            app.V_interp_z2 = Vz2;

            app.H_interp_x3 = Hx3;
            app.H_interp_z3 = Hz3;
            app.H_interp_x4 = Hx4;
            app.H_interp_z4 = Hz4;

            app.V_interp_y3 = Vy3;
            app.V_interp_z3 = Vz3;
            app.V_interp_y4 = Vy4;
            app.V_interp_z4 = Vz4;

        end

        function result = nanohole_dimension_analyzer(app, rectPos)

            app.H_boundaryIndices = [];
            app.V_boundaryIndices = [];

            xmin = rectPos(1);
            ymin = rectPos(2);
            xmax = xmin + rectPos(3);
            ymax = ymin + rectPos(4);

            ix = find(app.Xvec >= xmin & app.Xvec <= xmax);
            iy = find(app.Yvec >= ymin & app.Yvec <= ymax);

            subZ = app.Zgrid(iy, ix);

            [minZ, idx] = min(subZ(:));
            [row, col] = ind2sub(size(subZ), idx);

            minX = app.Xvec(ix(col));
            minY = app.Yvec(iy(row));

            result.minZ  = minZ;
            result.minX  = minX;
            result.minY  = minY;
            result.subZ  = subZ;
            app.minZval = minZ;
            result.ix    = ix;
            result.iy    = iy;
            result.row   = row;
            result.col   = col;

            hold(app.UIAxes,'on');
            hCenter = plot(app.UIAxes, minX, minY, 'rx', ...
                'LineWidth',2,'MarkerSize',12);

            app.centerMarkers(end+1) = hCenter;

            hold(app.UIAxes,'off');

            horiz_Z = result.subZ(result.row, :);
            horiz_X = app.Xvec(result.ix);

            vert_Z  = result.subZ(:, result.col);
            vert_Y  = app.Yvec(result.iy);

            app.H_profile_X = horiz_X(:);
            app.H_profile_Z = horiz_Z(:);
            app.V_profile_Y = vert_Y(:);
            app.V_profile_Z = vert_Z(:);

            app.H_selectedPoints = app.auto_ring_points_strict_lateral(horiz_X, horiz_Z);
            app.V_selectedPoints = app.auto_ring_points_strict_lateral(vert_Y,  vert_Z');

            app.build_interpolations();

            % ======================================================
            % SIMPLE BOUNDARY SELECTION (LEGACY VERSION)
            % ======================================================

            app.H_boundaryIndices = zeros(1,2);
            app.V_boundaryIndices = zeros(1,2);

            % ===============================
            % HORIZONTAL — LEFT
            % ===============================
            targetZ = app.H_interp_z1(1);

            [~, j] = min(abs(app.H_interp_z3 - targetZ));
            xBoundary = app.H_interp_x3(j);

            [~, j] = min(abs(app.H_interp_x1 - xBoundary));
            app.H_boundaryIndices(1) = j;


            % ===============================
            % HORIZONTAL — RIGHT
            % ===============================
            targetZ = app.H_interp_z2(end);

            [~, j] = min(abs(app.H_interp_z4 - targetZ));
            xBoundary = app.H_interp_x4(j);

            [~, j] = min(abs(app.H_interp_x2 - xBoundary));
            app.H_boundaryIndices(2) = j;


            % ===============================
            % VERTICAL — LOWER
            % ===============================
            targetZ = app.V_interp_z1(1);

            [~, j] = min(abs(app.V_interp_z3 - targetZ));
            yBoundary = app.V_interp_y3(j);

            [~, j] = min(abs(app.V_interp_y1 - yBoundary));
            app.V_boundaryIndices(1) = j;


            % ===============================
            % VERTICAL — UPPER
            % ===============================
            targetZ = app.V_interp_z2(end);

            [~, j] = min(abs(app.V_interp_z4 - targetZ));
            yBoundary = app.V_interp_y4(j);

            [~, j] = min(abs(app.V_interp_y2 - yBoundary));
            app.V_boundaryIndices(2) = j;

            % ==============================
            % Compute dimensions
            % ==============================
            [XL, XR] = app.compute_COM_H();
            app.Diameters_COM_X(end+1) = abs(XR - XL);

            [pL, pR] = app.computePeakDiameter_H();
            app.Diameters_Peak_X(end+1) = abs(pR - pL);

            [YL, YR] = app.compute_COM_V();
            app.Diameters_COM_Y(end+1) = abs(YR - YL);

            [pL, pR] = app.computePeakDiameter_V();
            app.Diameters_Peak_Y(end+1) = abs(pR - pL);

            app.DiametersInnerX(end+1) = abs( ...
                app.H_interp_x1(app.H_boundaryIndices(1)) ...
                - app.H_interp_x2(app.H_boundaryIndices(2)));

            app.DiametersInnerY(end+1) = abs( ...
                app.V_interp_y1(app.V_boundaryIndices(1)) ...
                - app.V_interp_y2(app.V_boundaryIndices(2)));

            % Outer Ring Diameters
            app.DiametersOuterX(end+1) = abs( ...
                app.H_interp_x1(1) - app.H_interp_x2(end));
            app.DiametersOuterY(end+1) = abs( ...
                app.V_interp_y1(1) - app.V_interp_y2(end));

            app.Depth(end+1) = abs(app.minZval) * 1e9;

            % Call diagnostic BEFORE clearing boundary indices
            show_COM_diagnostic(app, horiz_X, horiz_Z, vert_Y, vert_Z, XL, XR, YL, YR);

            app.H_boundaryIndices = [];
            app.V_boundaryIndices = [];

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowKeyPressFcn = @(src,event) keyPressHandler(app, event);

            % ✅ INITIALIZE STACKS
            app.drawnRectangles = images.roi.Rectangle.empty;
            app.rectpositions   = {};
        end

        % Button pushed function: EnterAFMxyzfileButton
        function EnterAFMxyzfileButtonPushed(app, event)
            try

                [file, location] = uigetfile("*.xyz");
                figure(app.UIFigure)
                app.UIAxes.Visible = "off";
                app.UIAxes.Visible = "on";

                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    app.full_file_path = fullfile(location, file);

                    raw = readmatrix(app.full_file_path, FileType="text", CommentStyle="#");

                    if size(raw,2) < 3
                        errordlg("Invalid XYZ file. It must contain 3 columns: X Y Z");
                        return;
                    end

                    Y = raw(:,1);
                    X = raw(:,2);
                    Z = raw(:,3);

                    ux = unique(X);
                    uy = unique(Y);

                    Nx = numel(ux);
                    Ny = numel(uy);

                    app.Zgrid = reshape(Z, Ny, Nx);
                    app.Zgrid = rot90(app.Zgrid, 1);
                    imagesc(ux, uy, app.Zgrid, "Parent", app.UIAxes);
                    set(app.UIAxes, 'YDir', 'normal');

                    app.Xvec = ux;
                    app.Yvec = uy;

                    axis(app.UIAxes, "image");
                    colormap(app.UIAxes, parula);
                    colorbar(app.UIAxes);

                    app.DoneButton.Visible = "on";
                    app.DoneButton.Enable = "off";
                    app.EnterAFMxyzfileButton.Visible = "off";
                    app.SmoothZvaluesButton.Visible = "on";
                    app.DrawRectangleButton.Visible = "on";
                    app.UndowithCtrlZLabel.Visible = "on";

                    app.UIAxes.XLabel.String = "X (m)";
                    app.UIAxes.YLabel.String = "Y (m)";
                    app.UIAxes.Title.String = "AFM Height Map (XYZ) – Draw rectangles around nanoholes";

                    axis(app.UIAxes, "tight");
                end
            catch
                errordlg("Invalid XYZ file. Please select a valid AFM XYZ file.");
            end

        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            try
                drawnow;  % ensure previous UI events flush

                rect = drawrectangle(app.UIAxes, 'Color', 'r', 'LineWidth', 1.5);

                % 🔒 Disable interaction AND deselect
                rect.InteractionsAllowed = "none";
                rect.Selected = false;

                % 🔑 Force focus back to UIFigure
                figure(app.UIFigure);

                if isempty(rect) || ~isvalid(rect)
                    return;
                end

                app.drawnRectangles(end+1) = rect;

                app.DoneButton.Enable = "on";

                app.rectpositions{end+1} = rect.Position;
                app.nanohole_dimension_analyzer(rect.Position);

                figure(app.UIFigure);

            catch ME
                uialert(app.UIFigure, ['Error during rectangle drawing: ' ME.message], 'Drawing Error');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

            % =========================================================
            % USE INPUT FILE NAME AS BASENAME
            % =========================================================

            % Extract name without extension from app.full_file_path
            [~, baseName, ~] = fileparts(app.full_file_path);

            % Sanitize just in case
            baseName = regexprep(baseName, '[^\w\- ]', '_');

            % Construct output filenames
            imgFileName = baseName + "_nanohole_numbering.png";
            xlsFileName = baseName + "_dimension_analysis.xlsx";

            % =========================================================
            % RESOLVE FULL PATHS (same folder as AFM input)
            % =========================================================
            [inputFolder, ~, ~] = fileparts(app.full_file_path);

            imgFullPath = fullfile(inputFolder, imgFileName);
            xlsFullPath = fullfile(inputFolder, xlsFileName);

            % ============================================================
            % ENSURE CLEAN EXCEL FILE (DELETE IF EXISTS)
            % ============================================================

            if isfile(xlsFullPath)
                delete(xlsFullPath);
            end

            disp("Saving PNG to:  " + imgFullPath);
            disp("Saving XLSX to: " + xlsFullPath);

            % =========================================================
            % RECREATE FIGURE WITH NUMBERED NANOHOLES
            % =========================================================
            fig = figure('Visible','on');  % must be on for proper render

            raw = readmatrix(app.full_file_path, FileType="text", CommentStyle="#");

            Y = raw(:,1);
            X = raw(:,2);
            Z = raw(:,3);

            ux = unique(X);
            uy = unique(Y);

            Nx = numel(ux);
            Ny = numel(uy);

            Zgrid = reshape(Z, Ny, Nx);
            Zgrid = rot90(Zgrid, 1);

            imagesc(ux, uy, Zgrid);
            set(gca,'YDir','normal');
            colormap(parula);
            colorbar;
            axis image;
            xlabel("X (m)");
            ylabel("Y (m)");
            title("Nanoholes with numbering");

            hold on;
            for k = 1:numel(app.rectpositions)
                pos = app.rectpositions{k};
                rectangle('Position', pos, 'EdgeColor','r','LineWidth',1.5);

                xc = pos(1) + pos(3)/2;
                yc = pos(2) + pos(4)/2;
                text(xc, yc, num2str(k),'Color','w','FontSize',14, ...
                    'HorizontalAlignment','center','FontWeight','bold');
            end
            hold off;

            drawnow;
            exportgraphics(fig, imgFullPath, 'Resolution', 300);

            close(fig);

            % ============================================================
            % BUILD DATA TABLE AND WRITE TO EXCEL
            % ============================================================

            nHoles = numel(app.Diameters_COM_X);
            nanoholeNums = (1:nHoles)';

            T = table( ...
                nanoholeNums, ...
                app.Diameters_COM_Y(:)   .* 1e9, ...
                app.Diameters_COM_X(:)   .* 1e9, ...
                app.Diameters_Peak_Y(:)  .* 1e9, ...
                app.Diameters_Peak_X(:)  .* 1e9, ...
                app.DiametersInnerY(:)   .* 1e9, ...   
                app.DiametersInnerX(:)   .* 1e9, ... 
                app.DiametersOuterY(:)   .* 1e9, ... 
                app.DiametersOuterX(:)   .* 1e9, ... 
                app.Depth(:), ...
                'VariableNames', { ...
                'NanoholeNumber', ...
                'Diameter_COM_Vertical_nm', ...
                'Diameter_COM_Horizontal_nm', ...
                'Diameter_Peak_Vertical_nm', ...
                'Diameter_Peak_Horizontal_nm', ...
                'Diameter_Inner_Vertical_nm', ...   
                'Diameter_Inner_Horizontal_nm', ... 
                'Diameter_Outer_Vertical_nm', ... 
                'Diameter_Outer_Horizontal_nm', ... 
                'Depth' ...
                } ...
                );

            % Round all numeric results (except index column)
            T{:,2:end} = round(T{:,2:end}, 2);

            % Write table to Excel
            writetable(T, xlsFullPath, 'Sheet', 1, 'Range', 'A1');


            % ============================================================
            % WRITE MEAN / STD / MEAN ± STD
            % ============================================================

            statsRow = nHoles + 3;   % Mean row
            stdRow   = nHoles + 4;   % StdDev row
            pmRow    = nHoles + 5;   % Mean ± Std row

            try
                % Try using Excel COM automation (Windows required)
                excel    = actxserver('Excel.Application');
                workbook = excel.Workbooks.Open(xlsFullPath);
                sheet    = workbook.Sheets.Item(1);

                % Row labels
                sheet.Range(['A' num2str(statsRow)]).Value = {'Mean'};
                sheet.Range(['A' num2str(stdRow)]).Value   = {'StdDev'};
                sheet.Range(['A' num2str(pmRow)]).Value    = {'Mean ± Std'};

                columns = {'B','C','D','E','F','G','H','I','J'};

                for c = 1:numel(columns)
                    col = columns{c};
                    dataRange = sprintf('%s2:%s%d', col, col, nHoles+1);

                    % Excel formulas
                    sheet.Range([col num2str(statsRow)]).Formula = ...
                        sprintf('=ROUND(AVERAGE(%s), 2)', dataRange);

                    sheet.Range([col num2str(stdRow)]).Formula = ...
                        sprintf('=ROUND(STDEV(%s), 2)', dataRange);

                    sheet.Range([col num2str(pmRow)]).Formula = ...
                        sprintf('=TEXT(AVERAGE(%s), "0.00") & " ± " & TEXT(STDEV(%s), "0.00")', ...
                        dataRange, dataRange);
                end

                % Save and close Excel
                workbook.Save();
                workbook.Close();
                excel.Quit();
                excel.delete();

                disp(['Saved Excel with formulas to: ', xlsFullPath]);

            catch
                % Fallback if COM fails (Mac/Linux or no Excel)
                warning('Excel COM unavailable. Writing static mean/std values.');

                means = varfun(@mean, T(:,2:end));
                stds  = varfun(@std,  T(:,2:end));

                % Create "Mean ± Std" text strings
                pmStrings = strcat( ...
                    string(round(means{1,:},2)), ...
                    " ± ", ...
                    string(round(stds{1,:},2)) ...
                    );

                % Matrix to write: Mean row, Std row, Mean±Std row
                statsData = [
                    nan, round(means{1,:},2);
                    nan, round(stds{1,:},2);
                    nan, pmStrings
                    ];

                writematrix(statsData, xlsFullPath, 'Sheet', 1, ...
                    'Range', sprintf('A%d', statsRow));
            end


            % ============================================================
            % RESTART APPLICATION
            % ============================================================

            appClass = class(app);
            delete(app);
            feval(appClass);

        end

        % Button pushed function: SmoothZvaluesButton
        function SmoothZvaluesButtonPushed(app, event)
            app.smooth_z_values = ~app.smooth_z_values;
            if app.smooth_z_values
                app.SmoothZvaluesButton.Text = "Undo smoothing";
            else
                app.SmoothZvaluesButton.Text = "Smooth Z values";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done';
            app.EnterAFMxyzfileButton = uibutton(app.GridLayout, 'push');
            app.EnterAFMxyzfileButton.ButtonPushedFcn = createCallbackFcn(app, @EnterAFMxyzfileButtonPushed, true);
            app.EnterAFMxyzfileButton.Text = 'Enter AFM .xyz file ';
            app.DrawRectangleButton = uibutton(app.GridLayout, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Visible = 'off';
            app.DrawRectangleButton.Text = 'Draw Rectangle';
            app.SmoothZvaluesButton = uibutton(app.GridLayout, 'push');
            app.SmoothZvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothZvaluesButtonPushed, true);
            app.SmoothZvaluesButton.Visible = 'off';
            app.SmoothZvaluesButton.Text = 'Smooth Z values';
            app.UndowithCtrlZLabel = uilabel(app.GridLayout);
            app.UndowithCtrlZLabel.Visible = 'off';
            app.UndowithCtrlZLabel.Text = 'Undo with Ctrl + Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = afmxyznanoholeanalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end